classdef lineprofilemultiplotapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        ColorDropDown       matlab.ui.control.DropDown
        ColorDropDownLabel  matlab.ui.control.Label
        HistogramCheckBox   matlab.ui.control.CheckBox
        Label               matlab.ui.control.Label
        RunButton           matlab.ui.control.Button
        DisplayName         matlab.ui.control.TextArea
        LoadButton          matlab.ui.control.Button
    end

    
    properties (Access = public)
       filename
       filepath
       histo
       color
      end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.histo = false;
            app.color = 'r.';

        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
                   [app.filename,path] = uigetfile('*.csv');
            app.filepath = fullfile(path,app.filename);
if isequal(app.filename,0)
   disp('User selected Cancel');
else
   app.DisplayName.Value = app.filename;
end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            lineprofilemultiplot(app.filepath, app.histo, app.color);
        end

        % Value changed function: HistogramCheckBox
        function HistogramCheckBoxValueChanged(app, event)
            app.histo = app.HistogramCheckBox.Value;
            
        end

        % Value changed function: ColorDropDown
        function ColorDropDownValueChanged(app, event)
            value = app.ColorDropDown.Value;
            if strcmpi(value,'Red')
                app.color = 'r.';
            else strcmpi(value,'Green')
                app.color = 'g.';
            end   
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load ';
            app.DisplayName = uitextarea(app.UIFigure);
            app.DisplayName.Editable = 'off';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Load the spreadsheet you want to plot. Compatible with output of Circle Pro gradient csv files';
            app.HistogramCheckBox = uicheckbox(app.UIFigure);
            app.HistogramCheckBox.ValueChangedFcn = createCallbackFcn(app, @HistogramCheckBoxValueChanged, true);
            app.HistogramCheckBox.Text = 'Histogram';
            app.ColorDropDownLabel = uilabel(app.UIFigure);
            app.ColorDropDownLabel.Text = 'Color';
            app.ColorDropDown = uidropdown(app.UIFigure);
            app.ColorDropDown.Items = {'Red', 'Green'};
            app.ColorDropDown.ValueChangedFcn = createCallbackFcn(app, @ColorDropDownValueChanged, true);
            app.ColorDropDown.Value = 'Red';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lineprofilemultiplotapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end