classdef mf_grinch < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        greentextarea                   matlab.ui.control.TextArea
        PETDetGreenButton               matlab.ui.control.Button
        Image                           matlab.ui.control.Image
        RunmaytakelongerdependingontimelapsesizeLabel  matlab.ui.control.Label
        redtextarea                     matlab.ui.control.TextArea
        IntDenTextArea                  matlab.ui.control.TextArea
        MFGRINCHLabel                   matlab.ui.control.Label
        FitOrderpEditField              matlab.ui.control.NumericEditField
        FitOrderpEditFieldLabel         matlab.ui.control.Label
        MultiplotCheckBox               matlab.ui.control.CheckBox
        PETDetRedButton                 matlab.ui.control.Button
        LoadIntDencsvButton             matlab.ui.control.Button
        RunButton                       matlab.ui.control.Button
        NumberofchambersEditField       matlab.ui.control.NumericEditField
        NumberofchambersEditFieldLabel  matlab.ui.control.Label
        HistogramsCheckBox              matlab.ui.control.CheckBox
    end

    
    properties (Access = public)
        petdet_red
        petdetpath_red
        petdet_green
        petdetpath_green
        intden
        intdenpath
        chambers
        histo
        log
        multiplot
        p

    end
  

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadIntDencsvButton
        function LoadIntDencsvButtonPushed(app, event)
              [app.intden,path] = uigetfile('*.csv');
            app.intdenpath = fullfile(path,app.intden);
            app.chambers = 160;
            app.log = 0;
            app.histo = 0;
            app.multiplot = 0;
            app.p = 3;
if isequal(app.intden,0)
   disp('User selected Cancel');
else
   disp(['User selected ', app.intden]);
   app.IntDenTextArea.Value = app.intden;
end
     
        end

        % Value changed function: NumberofchambersEditField
        function NumberofchambersEditFieldValueChanged(app, event)
         
            app.chambers = app.NumberofchambersEditField.Value;
          
        end

        % Value changed function: FitOrderpEditField
        function FitOrderpEditFieldValueChanged(app, event)
            p = app.FitOrderpEditField.Value;
            
        end

        % Callback function
        function LogCheckBoxValueChanged(app, event)
            app.log = app.LogCheckBox.Value;
            
        end

        % Button pushed function: PETDetRedButton
        function PETDetRedButtonPushed(app, event)
            [app.petdet_red,path] = uigetfile('*.csv');
            app.petdetpath_red = fullfile(path,app.petdet_red);
if isequal(app.petdet_red,0)
   disp('User selected Cancel');
else
   disp(['User selected ', app.petdet_red]);
   app.redtextarea.Value = app.petdet_red;
end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            grinchscript(app.petdetpath_red, app.petdetpath_green, app.intdenpath, app.chambers, app.log, app.histo, app.p);
        end

        % Callback function
        function IntDenTextValueChanged(app, event)
            value = app.petdet_red;
        
        end

        % Value changed function: IntDenTextArea
        function IntDenTextAreaValueChanged(app, event)
        
        end

        % Value changed function: redtextarea
        function redtextareaValueChanged(app, event)
            value = app.redtextarea.Value;
            
        end

        % Value changed function: HistogramsCheckBox
        function HistogramsCheckBoxValueChanged(app, event)
            app.histo = app.HistogramsCheckBox.Value;
            
        end

        % Value changed function: MultiplotCheckBox
        function MultiplotCheckBoxValueChanged(app, event)
            app.multiplot = app.MultiplotCheckBox.Value;
            
        end

        % Button pushed function: PETDetGreenButton
        function PETDetGreenButtonPushed(app, event)
                  [app.petdet_green,path] = uigetfile('*.csv');
            app.petdetpath_green = fullfile(path,app.petdet_green);
if isequal(app.petdet_green,0)
   disp('User selected Cancel');
else
   disp(['User selected ', app.petdet_green]);
   app.greentextarea.Value = app.petdet_green;
end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.HistogramsCheckBox = uicheckbox(app.UIFigure);
            app.HistogramsCheckBox.ValueChangedFcn = createCallbackFcn(app, @HistogramsCheckBoxValueChanged, true);
            app.HistogramsCheckBox.Text = 'Histograms';
            app.NumberofchambersEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofchambersEditFieldLabel.Text = 'Number of chambers';
            app.NumberofchambersEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofchambersEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofchambersEditFieldValueChanged, true);
            app.NumberofchambersEditField.Value = 160;
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.LoadIntDencsvButton = uibutton(app.UIFigure, 'push');
            app.LoadIntDencsvButton.ButtonPushedFcn = createCallbackFcn(app, @LoadIntDencsvButtonPushed, true);
            app.LoadIntDencsvButton.Text = 'Load IntDen.csv';
            app.PETDetRedButton = uibutton(app.UIFigure, 'push');
            app.PETDetRedButton.ButtonPushedFcn = createCallbackFcn(app, @PETDetRedButtonPushed, true);
            app.PETDetRedButton.Text = 'PET Det Red';
            app.MultiplotCheckBox = uicheckbox(app.UIFigure);
            app.MultiplotCheckBox.ValueChangedFcn = createCallbackFcn(app, @MultiplotCheckBoxValueChanged, true);
            app.MultiplotCheckBox.Text = 'Multiplot';
            app.FitOrderpEditFieldLabel = uilabel(app.UIFigure);
            app.FitOrderpEditFieldLabel.Text = 'Fit Order (p)';
            app.FitOrderpEditField = uieditfield(app.UIFigure, 'numeric');
            app.FitOrderpEditField.ValueChangedFcn = createCallbackFcn(app, @FitOrderpEditFieldValueChanged, true);
            app.FitOrderpEditField.Value = 3;
            app.MFGRINCHLabel = uilabel(app.UIFigure);
            app.MFGRINCHLabel.Text = 'MF GRINCH';
            app.IntDenTextArea = uitextarea(app.UIFigure);
            app.IntDenTextArea.ValueChangedFcn = createCallbackFcn(app, @IntDenTextAreaValueChanged, true);
            app.IntDenTextArea.Editable = 'off';
            app.redtextarea = uitextarea(app.UIFigure);
            app.redtextarea.ValueChangedFcn = createCallbackFcn(app, @redtextareaValueChanged, true);
            app.redtextarea.Editable = 'off';
            app.RunmaytakelongerdependingontimelapsesizeLabel = uilabel(app.UIFigure);
            app.RunmaytakelongerdependingontimelapsesizeLabel.Text = '(Run may take longer depending on timelapse size)';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'grinch.png');
            app.PETDetGreenButton = uibutton(app.UIFigure, 'push');
            app.PETDetGreenButton.ButtonPushedFcn = createCallbackFcn(app, @PETDetGreenButtonPushed, true);
            app.PETDetGreenButton.Text = 'PET Det Green';
            app.greentextarea = uitextarea(app.UIFigure);
            app.greentextarea.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mf_grinch

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end