classdef skimgrinchslopechange < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        NumberofchangesEditField        matlab.ui.control.NumericEditField
        NumberofchangesEditFieldLabel   matlab.ui.control.Label
        YOLOSwitch                      matlab.ui.control.ToggleSwitch
        YOLOLabel                       matlab.ui.control.Label
        MultiplotOptionsDropDown        matlab.ui.control.DropDown
        MultiplotOptionsDropDownLabel   matlab.ui.control.Label
        PetDetTextArea                  matlab.ui.control.TextArea
        IntDenTextArea                  matlab.ui.control.TextArea
        SkiPatrolLabel                  matlab.ui.control.Label
        LogCheckBox                     matlab.ui.control.CheckBox
        LoadPetDetcsvButton             matlab.ui.control.Button
        LoadIntDencsvButton             matlab.ui.control.Button
        RunButton                       matlab.ui.control.Button
        NumberofchambersEditField       matlab.ui.control.NumericEditField
        NumberofchambersEditFieldLabel  matlab.ui.control.Label
        HistogramsCheckBox              matlab.ui.control.CheckBox
    end

    
    properties (Access = public)
        petdet
        petdetpath
        intden
        intdenpath
        nChambers
        isHisto
        isLog
        isMultiplot
        p
        isCellplot
        numChanges

    end
  

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.nChambers = 160;
%             app.intdenpath = 'C:\Users\bensc\Desktop\Local Research\MATLAB\Grinch 2.0\testing_data\test_intden_single.csv';
%             app.petdetpath = 'C:\Users\bensc\Desktop\Local Research\MATLAB\Grinch 2.0\testing_data\test_petdet_single.csv';
            app.numChanges = 2;
            app.isLog = 0;
            app.isHisto = 0;
            app.isMultiplot = 1;
            app.p = 3;
            app.isCellplot = 0;
        end

        % Button pushed function: LoadIntDencsvButton
        function LoadIntDencsvButtonPushed(app, event)
            [app.intden,path] = uigetfile('*.csv');
            app.intdenpath = fullfile(path,app.intden);
        if isequal(app.intden,0)
            disp('User selected Cancel');
        else
            disp(['User selected ', app.intden]);
            app.IntDenTextArea.Value = app.intden;
        end
     
        end

        % Value changed function: NumberofchambersEditField
        function NumberofchambersEditFieldValueChanged(app, event)
         
            app.nChambers = app.NumberofchambersEditField.Value;
          
        end

        % Callback function
        function FitOrderpEditFieldValueChanged(app, event)
            app.p = app.FitOrderpEditField.Value;
            
        end

        % Value changed function: LogCheckBox
        function LogCheckBoxValueChanged(app, event)
            app.isLog = app.LogCheckBox.Value;
            
        end

        % Button pushed function: LoadPetDetcsvButton
        function LoadPetDetcsvButtonPushed(app, event)
            [app.petdet,path] = uigetfile('*.csv');
            app.petdetpath = fullfile(path,app.petdet);
if isequal(app.petdet,0)
   disp('User selected Cancel');
else
   disp(['User selected ', app.petdet]);
   app.PetDetTextArea.Value = app.petdet;
end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            skimgrinchslopechange20221026(app.petdetpath, app.intdenpath, ...
                app.nChambers, app.isLog, app.isHisto, app.numChanges, ...
                app.isMultiplot, app.isCellplot);
        end

        % Callback function
        function IntDenTextValueChanged(app, event)
           
        end

        % Value changed function: IntDenTextArea
        function IntDenTextAreaValueChanged(app, event)
        
        end

        % Value changed function: PetDetTextArea
        function PetDetTextAreaValueChanged(app, event)
       
        end

        % Value changed function: HistogramsCheckBox
        function HistogramsCheckBoxValueChanged(app, event)
            app.isHisto = app.HistogramsCheckBox.Value;
            
        end

        % Callback function
        function MultiplotCheckBoxValueChanged(app, event)
            app.isMultiplot = app.MultiplotCheckBox.Value;
            
        end

        % Value changed function: MultiplotOptionsDropDown
        function MultiplotOptionsDropDownValueChanged(app, event)
            value = app.MultiplotOptionsDropDown.Value;
            if strcmpi(value,'Perform Multiplot')
        app.isMultiplot = 1;
        app.isCellplot = 0;
    elseif strcmpi(value,'Cell Only Plot')
        app.isCellplot = 1;
        app.isMultiplot = 0;
    else 
        app.isMultiplot = 0 ;
        app.isCellplot = 0;
    end   
        end

        % Value changed function: NumberofchangesEditField
        function NumberofchangesEditFieldValueChanged(app, event)
            app.numChanges = app.NumberofchangesEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.HistogramsCheckBox = uicheckbox(app.UIFigure);
            app.HistogramsCheckBox.ValueChangedFcn = createCallbackFcn(app, @HistogramsCheckBoxValueChanged, true);
            app.HistogramsCheckBox.Text = 'Histograms';
            app.NumberofchambersEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofchambersEditFieldLabel.Text = 'Number of chambers';
            app.NumberofchambersEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofchambersEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofchambersEditFieldValueChanged, true);
            app.NumberofchambersEditField.Value = 160;
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.LoadIntDencsvButton = uibutton(app.UIFigure, 'push');
            app.LoadIntDencsvButton.ButtonPushedFcn = createCallbackFcn(app, @LoadIntDencsvButtonPushed, true);
            app.LoadIntDencsvButton.Text = 'Load IntDen.csv';
            app.LoadPetDetcsvButton = uibutton(app.UIFigure, 'push');
            app.LoadPetDetcsvButton.ButtonPushedFcn = createCallbackFcn(app, @LoadPetDetcsvButtonPushed, true);
            app.LoadPetDetcsvButton.Text = 'Load PetDet.csv';
            app.LogCheckBox = uicheckbox(app.UIFigure);
            app.LogCheckBox.ValueChangedFcn = createCallbackFcn(app, @LogCheckBoxValueChanged, true);
            app.LogCheckBox.Text = 'Log';
            app.SkiPatrolLabel = uilabel(app.UIFigure);
            app.SkiPatrolLabel.Text = 'Ski Patrol';
            app.IntDenTextArea = uitextarea(app.UIFigure);
            app.IntDenTextArea.ValueChangedFcn = createCallbackFcn(app, @IntDenTextAreaValueChanged, true);
            app.IntDenTextArea.Editable = 'off';
            app.PetDetTextArea = uitextarea(app.UIFigure);
            app.PetDetTextArea.ValueChangedFcn = createCallbackFcn(app, @PetDetTextAreaValueChanged, true);
            app.PetDetTextArea.Editable = 'off';
            app.MultiplotOptionsDropDownLabel = uilabel(app.UIFigure);
            app.MultiplotOptionsDropDownLabel.Text = 'Multiplot Options';
            app.MultiplotOptionsDropDown = uidropdown(app.UIFigure);
            app.MultiplotOptionsDropDown.Items = {'Perform Multiplot', 'Cell Only Plot', 'No Plot'};
            app.MultiplotOptionsDropDown.ValueChangedFcn = createCallbackFcn(app, @MultiplotOptionsDropDownValueChanged, true);
            app.MultiplotOptionsDropDown.Value = 'Perform Multiplot';
            app.YOLOLabel = uilabel(app.UIFigure);
            app.YOLOLabel.Text = 'YOLO?';
            app.YOLOSwitch = uiswitch(app.UIFigure, 'toggle');
            app.NumberofchangesEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofchangesEditFieldLabel.Text = 'Number of changes';
            app.NumberofchangesEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofchangesEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofchangesEditFieldValueChanged, true);
            app.NumberofchangesEditField.Value = 2;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = skimgrinchslopechange

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end