classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GrinchLabel                     matlab.ui.control.Label
        LogCheckBox                     matlab.ui.control.CheckBox
        FitOrderpEditField              matlab.ui.control.NumericEditField
        FitOrderpEditFieldLabel         matlab.ui.control.Label
        MultiplotCheckBox               matlab.ui.control.CheckBox
        LoadPetDetcsvButton             matlab.ui.control.Button
        LoadIntDencsvButton             matlab.ui.control.Button
        CancelButton                    matlab.ui.control.Button
        RunButton                       matlab.ui.control.Button
        NumberofchambersEditField       matlab.ui.control.NumericEditField
        NumberofchambersEditFieldLabel  matlab.ui.control.Label
        HistogramsCheckBox              matlab.ui.control.CheckBox
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadIntDencsvButton
        function LoadIntDencsvButtonPushed(app, event)
              [intden,path] = uigetfile('*.csv');
if isequal(intden,0)
   disp('User selected Cancel');
else
   disp(['User selected ', intden]);
end
     
        end

        % Value changed function: NumberofchambersEditField
        function NumberofchambersEditFieldValueChanged(app, event)
            chambers = app.NumberofchambersEditField.Value;
            
        end

        % Value changed function: FitOrderpEditField
        function FitOrderpEditFieldValueChanged(app, event)
            p = app.FitOrderpEditField.Value;
            
        end

        % Value changed function: LogCheckBox
        function LogCheckBoxValueChanged(app, event)
            log = app.LogCheckBox.Value;
            
        end

        % Button pushed function: LoadPetDetcsvButton
        function LoadPetDetcsvButtonPushed(app, event)
            [petdet,path] = uigetfile('*.csv');
if isequal(petdet,0)
   disp('User selected Cancel');
else
   disp(['User selected ', petdet]);
end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
       GRINCH     
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.HistogramsCheckBox = uicheckbox(app.UIFigure);
            app.HistogramsCheckBox.Text = 'Histograms';
            app.NumberofchambersEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofchambersEditFieldLabel.Text = 'Number of chambers';
            app.NumberofchambersEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofchambersEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofchambersEditFieldValueChanged, true);
            app.NumberofchambersEditField.Value = 160;
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.Text = 'Cancel';
            app.LoadIntDencsvButton = uibutton(app.UIFigure, 'push');
            app.LoadIntDencsvButton.ButtonPushedFcn = createCallbackFcn(app, @LoadIntDencsvButtonPushed, true);
            app.LoadIntDencsvButton.Text = 'Load IntDen.csv';
            app.LoadPetDetcsvButton = uibutton(app.UIFigure, 'push');
            app.LoadPetDetcsvButton.ButtonPushedFcn = createCallbackFcn(app, @LoadPetDetcsvButtonPushed, true);
            app.LoadPetDetcsvButton.Text = 'Load PetDet.csv';
            app.MultiplotCheckBox = uicheckbox(app.UIFigure);
            app.MultiplotCheckBox.Text = 'Multiplot';
            app.FitOrderpEditFieldLabel = uilabel(app.UIFigure);
            app.FitOrderpEditFieldLabel.Text = 'Fit Order (p)';
            app.FitOrderpEditField = uieditfield(app.UIFigure, 'numeric');
            app.FitOrderpEditField.ValueChangedFcn = createCallbackFcn(app, @FitOrderpEditFieldValueChanged, true);
            app.FitOrderpEditField.Value = 3;
            app.LogCheckBox = uicheckbox(app.UIFigure);
            app.LogCheckBox.ValueChangedFcn = createCallbackFcn(app, @LogCheckBoxValueChanged, true);
            app.LogCheckBox.Text = 'Log';
            app.GrinchLabel = uilabel(app.UIFigure);
            app.GrinchLabel.Text = 'Grinch';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end