classdef fICP_Analyzer_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        GreenLabel         matlab.ui.control.Label
        RedLabel           matlab.ui.control.Label
        DisplayName_2      matlab.ui.control.TextArea
        LoadButton_2       matlab.ui.control.Button
        HistogramCheckBox  matlab.ui.control.CheckBox
        Label              matlab.ui.control.Label
        RunButton          matlab.ui.control.Button
        DisplayName        matlab.ui.control.TextArea
        LoadButton         matlab.ui.control.Button
    end

    
    properties (Access = public)
       filename
       filepath
       filename_2
       filepath_2
       histo
      end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
                   [app.filename,path] = uigetfile('*.csv');
            app.filepath = fullfile(path,app.filename);
            app.histo = false;
if isequal(app.filename,0)
   disp('User selected Cancel');
else
   app.DisplayName.Value = app.filename;
end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            fICP_Analyzer(app.filepath, app.filepath_2, app.histo);
        end

        % Value changed function: HistogramCheckBox
        function HistogramCheckBoxValueChanged(app, event)
            app.histo = app.HistogramCheckBox.Value;
            
        end

        % Button pushed function: LoadButton_2
        function LoadButton_2Pushed(app, event)
            [app.filename_2,path] = uigetfile('*.csv');
            app.filepath_2 = fullfile(path,app.filename_2);
            if isequal(app.filename_2,0)
   disp('User selected Cancel');
else
   app.DisplayName_2.Value = app.filename_2;
end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load ';
            app.DisplayName = uitextarea(app.UIFigure);
            app.DisplayName.Editable = 'off';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Load the spreadsheet you want to plot. Compatible with output of Circle Pro gradient csv files';
            app.HistogramCheckBox = uicheckbox(app.UIFigure);
            app.HistogramCheckBox.ValueChangedFcn = createCallbackFcn(app, @HistogramCheckBoxValueChanged, true);
            app.HistogramCheckBox.Text = 'Histogram';
            app.LoadButton_2 = uibutton(app.UIFigure, 'push');
            app.LoadButton_2.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_2Pushed, true);
            app.LoadButton_2.Text = 'Load ';
            app.DisplayName_2 = uitextarea(app.UIFigure);
            app.DisplayName_2.Editable = 'off';
            app.RedLabel = uilabel(app.UIFigure);
            app.RedLabel.Text = 'Red';
            app.GreenLabel = uilabel(app.UIFigure);
            app.GreenLabel.Text = 'Green';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fICP_Analyzer_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end